classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        HAPPYBIRTHDATETHEMEButton  matlab.ui.control.Button
        LONGPRESSButton            matlab.ui.control.Button
        RECORDButton               matlab.ui.control.Button
        A5Button_2                 matlab.ui.control.Button
        G5Button_2                 matlab.ui.control.Button
        F5Button_2                 matlab.ui.control.Button
        D5Button_2                 matlab.ui.control.Button
        C5Button_2                 matlab.ui.control.Button
        A4Button_2                 matlab.ui.control.Button
        G4Button_2                 matlab.ui.control.Button
        F4Button_2                 matlab.ui.control.Button
        D4Button_2                 matlab.ui.control.Button
        C4Button_2                 matlab.ui.control.Button
        A3Button_2                 matlab.ui.control.Button
        G3Button_2                 matlab.ui.control.Button
        F3Button_2                 matlab.ui.control.Button
        G5Button                   matlab.ui.control.Button
        A5Button                   matlab.ui.control.Button
        B5Button                   matlab.ui.control.Button
        C6Button                   matlab.ui.control.Button
        D5Button                   matlab.ui.control.Button
        E5Button                   matlab.ui.control.Button
        F5Button                   matlab.ui.control.Button
        C5Button                   matlab.ui.control.Button
        B4Button                   matlab.ui.control.Button
        A4Button                   matlab.ui.control.Button
        G4Button                   matlab.ui.control.Button
        C4Button                   matlab.ui.control.Button
        D4Button                   matlab.ui.control.Button
        E4Button                   matlab.ui.control.Button
        F4Button                   matlab.ui.control.Button
        B3Button                   matlab.ui.control.Button
        A3Button                   matlab.ui.control.Button
        G3Button                   matlab.ui.control.Button
        F3Button                   matlab.ui.control.Button
        Image                      matlab.ui.control.Image
    end

    properties (Access = private)
        samplefreq=44100;
        time=4;
        rec=0;
        song=[]
    end
    methods (Access = private)
        
        function wave = wavefunc(app,notenum,time)
            t = 0:1/app.samplefreq:4/time;
            %Formula to determine frequency of anote from its notenumber
            freq = 440*2^((notenum-49)/12);
            %Code to waves to simulate the sound of a piano
            wave = sin(1* 2 * pi *freq * t) .* exp(-0.0004 * 2 * pi * freq * t) / 1;
            wave = wave+sin(2* 2 * pi *freq * t) .* exp(-0.0004 * 2 * pi * freq * t) / 2;
            wave = wave+sin(3* 2 * pi *freq * t) .* exp(-0.0004 * 2 * pi * freq * t) / 4;
            wave = wave+sin(4* 2 * pi *freq * t) .* exp(-0.0004 * 2 * pi * freq * t) / 8;
            wave = wave+sin(5* 2 * pi *freq * t) .* exp(-0.0004 * 2 * pi * freq * t) / 16;
            wave = wave+sin(6* 2 * pi *freq * t) .* exp(-0.0004 * 2 * pi * freq * t) / 32;
            wave= wave+(wave.*wave.*wave);
            wave=wave/6;
          end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: F3Button
        function F3ButtonPushed(app, event)
            notenum=33;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: A5Button
        function A5ButtonPushed(app, event)
            notenum=61;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: G3Button
        function G3ButtonPushed(app, event)
            notenum=35;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: A3Button
        function A3ButtonPushed(app, event)
            notenum=37;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: B3Button
        function B3ButtonPushed(app, event)
            notenum=38;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: C4Button
        function C4ButtonPushed(app, event)
            notenum=40;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: D4Button
        function D4ButtonPushed(app, event)
            notenum=42;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: E4Button
        function E4ButtonPushed(app, event)
            notenum=44;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: F4Button
        function F4ButtonPushed(app, event)
            notenum=45;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: G4Button
        function G4ButtonPushed(app, event)
            notenum=47;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: A4Button
        function A4ButtonPushed(app, event)
            notenum=49;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: B4Button
        function B4ButtonPushed(app, event)
            notenum=51;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: C5Button
        function C5ButtonPushed(app, event)
            notenum=52;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: D5Button
        function D5ButtonPushed(app, event)
            notenum=54;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: E5Button
        function E5ButtonPushed(app, event)
            notenum=44;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: F5Button
        function F5ButtonPushed(app, event)
            notenum=57;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: G5Button
        function G5ButtonPushed(app, event)
            notenum=59;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: B5Button
        function B5ButtonPushed(app, event)
            notenum=63;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: C6Button
        function C6ButtonPushed(app, event)
            notenum=64;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: F3Button_2
        function F3Button_2Pushed(app, event)
            notenum=34;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: G3Button_2
        function G3Button_2Pushed(app, event)
            notenum=36;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: A3Button_2
        function A3Button_2Pushed(app, event)
            notenum=38;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: C4Button_2
        function C4Button_2Pushed(app, event)
            notenum=41;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: D4Button_2
        function D4Button_2Pushed(app, event)
            notenum=43;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: F4Button_2
        function F4Button_2Pushed(app, event)
            notenum=46;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: G4Button_2
        function G4Button_2Pushed(app, event)
            notenum=48;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: A4Button_2
        function A4Button_2Pushed(app, event)
            notenum=50;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: C5Button_2
        function C5Button_2Pushed(app, event)
            notenum=53;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: D5Button_2
        function D5Button_2Pushed(app, event)
            notenum=55;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: F5Button_2
        function F5Button_2Pushed(app, event)
            notenum=58;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: G5Button_2
        function G5Button_2Pushed(app, event)
            notenum=60;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end

        % Button pushed function: A5Button_2
        function A5Button_2Pushed(app, event)
            notenum=62;                
            wave=app.wavefunc(notenum,app.time);                
            sound(wave,app.samplefreq,24);                
            if (app.rec==1)                    
                app.song=[app.song wave];  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.F3Button = uibutton(app.UIFigure, 'push');
            app.F3Button.ButtonPushedFcn = createCallbackFcn(app, @F3ButtonPushed, true);
            app.F3Button.Text = 'F3';
            app.G3Button = uibutton(app.UIFigure, 'push');
            app.G3Button.ButtonPushedFcn = createCallbackFcn(app, @G3ButtonPushed, true);
            app.G3Button.Text = 'G3';
            app.A3Button = uibutton(app.UIFigure, 'push');
            app.A3Button.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.A3Button.Text = 'A3';
            app.B3Button = uibutton(app.UIFigure, 'push');
            app.B3Button.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3Button.Text = 'B3';
            app.F4Button = uibutton(app.UIFigure, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4Button.Text = 'F4';
            app.E4Button = uibutton(app.UIFigure, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4ButtonPushed, true);
            app.E4Button.Text = 'E4';
            app.D4Button = uibutton(app.UIFigure, 'push');
            app.D4Button.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.D4Button.Text = 'D4';
            app.C4Button = uibutton(app.UIFigure, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.C4Button.Text = 'C4';
            app.G4Button = uibutton(app.UIFigure, 'push');
            app.G4Button.ButtonPushedFcn = createCallbackFcn(app, @G4ButtonPushed, true);
            app.G4Button.Text = 'G4';
            app.A4Button = uibutton(app.UIFigure, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.A4Button.Text = 'A4';
            app.B4Button = uibutton(app.UIFigure, 'push');
            app.B4Button.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4Button.Text = 'B4';
            app.C5Button = uibutton(app.UIFigure, 'push');
            app.C5Button.ButtonPushedFcn = createCallbackFcn(app, @C5ButtonPushed, true);
            app.C5Button.Text = 'C5';
            app.F5Button = uibutton(app.UIFigure, 'push');
            app.F5Button.ButtonPushedFcn = createCallbackFcn(app, @F5ButtonPushed, true);
            app.F5Button.Text = 'F5';
            app.E5Button = uibutton(app.UIFigure, 'push');
            app.E5Button.ButtonPushedFcn = createCallbackFcn(app, @E5ButtonPushed, true);
            app.E5Button.Text = 'E5';
            app.D5Button = uibutton(app.UIFigure, 'push');
            app.D5Button.ButtonPushedFcn = createCallbackFcn(app, @D5ButtonPushed, true);
            app.D5Button.Text = 'D5';
            app.C6Button = uibutton(app.UIFigure, 'push');
            app.C6Button.ButtonPushedFcn = createCallbackFcn(app, @C6ButtonPushed, true);
            app.C6Button.Text = 'C6';
            app.B5Button = uibutton(app.UIFigure, 'push');
            app.B5Button.ButtonPushedFcn = createCallbackFcn(app, @B5ButtonPushed, true);
            app.B5Button.Text = 'B5';
            app.A5Button = uibutton(app.UIFigure, 'push');
            app.A5Button.ButtonPushedFcn = createCallbackFcn(app, @A5ButtonPushed, true);
            app.A5Button.Text = 'A5';
            app.G5Button = uibutton(app.UIFigure, 'push');
            app.G5Button.ButtonPushedFcn = createCallbackFcn(app, @G5ButtonPushed, true);
            app.G5Button.Text = 'G5';
            app.F3Button_2 = uibutton(app.UIFigure, 'push');
            app.F3Button_2.ButtonPushedFcn = createCallbackFcn(app, @F3Button_2Pushed, true);
            app.F3Button_2.Text = 'F3#';
            app.G3Button_2 = uibutton(app.UIFigure, 'push');
            app.G3Button_2.ButtonPushedFcn = createCallbackFcn(app, @G3Button_2Pushed, true);
            app.G3Button_2.Text = 'G3#';
            app.A3Button_2 = uibutton(app.UIFigure, 'push');
            app.A3Button_2.ButtonPushedFcn = createCallbackFcn(app, @A3Button_2Pushed, true);
            app.A3Button_2.Text = 'A3#';
            app.C4Button_2 = uibutton(app.UIFigure, 'push');
            app.C4Button_2.ButtonPushedFcn = createCallbackFcn(app, @C4Button_2Pushed, true);
            app.C4Button_2.Text = 'C4#';
            app.D4Button_2 = uibutton(app.UIFigure, 'push');
            app.D4Button_2.ButtonPushedFcn = createCallbackFcn(app, @D4Button_2Pushed, true);
            app.D4Button_2.Text = 'D4#';
            app.F4Button_2 = uibutton(app.UIFigure, 'push');
            app.F4Button_2.ButtonPushedFcn = createCallbackFcn(app, @F4Button_2Pushed, true);
            app.F4Button_2.Text = 'F4#';
            app.G4Button_2 = uibutton(app.UIFigure, 'push');
            app.G4Button_2.ButtonPushedFcn = createCallbackFcn(app, @G4Button_2Pushed, true);
            app.G4Button_2.Text = 'G4#';
            app.A4Button_2 = uibutton(app.UIFigure, 'push');
            app.A4Button_2.ButtonPushedFcn = createCallbackFcn(app, @A4Button_2Pushed, true);
            app.A4Button_2.Text = 'A4#';
            app.C5Button_2 = uibutton(app.UIFigure, 'push');
            app.C5Button_2.ButtonPushedFcn = createCallbackFcn(app, @C5Button_2Pushed, true);
            app.C5Button_2.Text = 'C5#';
            app.D5Button_2 = uibutton(app.UIFigure, 'push');
            app.D5Button_2.ButtonPushedFcn = createCallbackFcn(app, @D5Button_2Pushed, true);
            app.D5Button_2.Text = 'D5#';
            app.F5Button_2 = uibutton(app.UIFigure, 'push');
            app.F5Button_2.ButtonPushedFcn = createCallbackFcn(app, @F5Button_2Pushed, true);
            app.F5Button_2.Text = 'F5#';
            app.G5Button_2 = uibutton(app.UIFigure, 'push');
            app.G5Button_2.ButtonPushedFcn = createCallbackFcn(app, @G5Button_2Pushed, true);
            app.G5Button_2.Text = 'G5#';
            app.A5Button_2 = uibutton(app.UIFigure, 'push');
            app.A5Button_2.ButtonPushedFcn = createCallbackFcn(app, @A5Button_2Pushed, true);
            app.A5Button_2.Text = 'A5#';
            app.RECORDButton = uibutton(app.UIFigure, 'push');
            app.RECORDButton.Text = 'RECORD';
            app.LONGPRESSButton = uibutton(app.UIFigure, 'push');
            app.LONGPRESSButton.Text = 'LONG PRESS';
            app.HAPPYBIRTHDATETHEMEButton = uibutton(app.UIFigure, 'push');
            app.HAPPYBIRTHDATETHEMEButton.Text = 'HAPPY BIRTHDATE THEME';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end